classdef SerialVisualizator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ImpostazioniMenu      matlab.ui.container.Menu
        KeilPathMenu          matlab.ui.container.Menu
        AggiornaSoftwareMenu  matlab.ui.container.Menu
        SelezionaSerialeMenu  matlab.ui.container.Menu
        RunningLabel          matlab.ui.control.Label
        StartSendDataButton   matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Timer;
        Time;
        SerialPort;
        Iteration=1;
        TimePassed=0;
        Path="";
        filename='Settings.mat';
        TxData;
        InputSent=1;
        lenght
    end
    
    methods (Access = private)
        
        function readSerialData(app,src,~)
            app.Iteration=app.Iteration+1;
            if(app.Iteration>1)
                app.TimePassed(app.Iteration)=app.TimePassed(app.Iteration-1)+0.01;
            end
            raw = readline(src);
            data = double(uint8(raw{1}));
            app.Time(app.Iteration)=data(2)+data(1)*256;
            if(app.Iteration>300)
                plot(app.UIAxes,app.TimePassed(app.Iteration-300:app.Iteration),app.Time(app.Iteration-300:app.Iteration));
            else
                plot(app.UIAxes,app.TimePassed,app.Time);
            end
            if app.InputSent<app.lenght
                write(app.SerialPort,app.TxData.ax.Values.Data(app.InputSent),"double");
                write(app.SerialPort,app.TxData.delta.Values.Data(app.InputSent),"double");
                write(app.SerialPort,app.TxData.r.Values.Data(app.InputSent),"double");
                write(app.SerialPort,app.TxData.steering.Values.Data(app.InputSent),"double");
                write(app.SerialPort,app.TxData.vx.Values.Data(app.InputSent),"double");
                write(app.SerialPort,app.TxData.vy.Values.Data(app.InputSent),"double");
                write(app.SerialPort,app.TxData.yrd.Values.Data(app.InputSent),"double");
                app.InputSent=app.InputSent+1;
            else
                app.InputSent=1;
                app.RunningLabel.FontColor=[255 165 0]/255;
                app.RunningLabel.Text="Waiting";
            end

        end
        
        function CheckPath(app,path,CallerFunction)   
            if(path~="")
                sysout=system("if exist "+ path+"UV4.exe" +" (exit /b 1)  else (exit /b 2)");
                if(sysout==1)
                    app.Path=path;
                    save(app.filename,'path');
                else
                    errordlg("Keil path is wrong,"+newline+"invalid path");
                    if (CallerFunction=="Startup")
                        app.Path="";
                    end
                end
            else
                 errordlg("Keil path is empty,"+newline+"invalid path");
                 if (CallerFunction=="Startup")
                    app.Path="";
                 end
            end
        end
        
        function SerialCheck(app)
            freeports=serialportlist("available");
            if(freeports~="")
                if(length(freeports)>1);[index,~]=listdlg('ListString',freeports);else;index=1;end
                app.SerialPort = serialport(freeports(index),115800);
                configureTerminator(app.SerialPort,"CR/LF","CR/LF");
                configureCallback(app.SerialPort,"terminator",@(src, event) readSerialData(app,src,event));
            else 
                errordlg("No Serial Port Connected");
            end     
            
        end
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RunningLabel.FontColor=[255 165 0]/255;
            app.RunningLabel.Text="Waiting";
            app.Path=load(app.filename,'path').path;
            CheckPath(app,app.Path,"Startup")
            app.SerialCheck();
            ylabel(app.UIAxes,'[us]') 
            xlabel(app.UIAxes,'[s]') 
            app.TxData=load("Open_Loop_Input_Output.mat");
            app.lenght=length(app.TxData.steering.Values.Data);
            app.Timer = timer('TimerFcn',@app.TimerCallback,'StartDelay',0,'Period',0.01,'ExecutionMode','fixedSpacing');
            
        end

        % Menu selected function: ImpostazioniMenu
        function ImpostazioniMenuSelected(app, event)
            
        end

        % Menu selected function: KeilPathMenu
        function KeilPathMenuSelected(app, event)
            path=inputdlg("Write Keil installation path (where there is UV4.exe file)","DefinePath",1,app.Path);
            CheckPath(app,path,"Menu");
        end

        % Menu selected function: AggiornaSoftwareMenu
        function AggiornaSoftwareMenuSelected(app, event)
            if(app.Path~="")
                mkdir CodiceGenerato\;
                cd CodiceGenerato\;
                slbuild("TVModel");
                cd ..;
                copyfile CodiceGenerato\TVModel_ert_rtw\TVModel.c Keil\C_Code_Sources\Src;
                copyfile CodiceGenerato\TVModel_ert_rtw\TVModel.h Keil\C_Code_Sources\Inc;
                copyfile CodiceGenerato\TVModel_ert_rtw\rtwtypes.h Keil\C_Code_Sources\Inc;
                sysout=system(app.Path+"UV4.exe"+" -b Keil\MDK-ARM\ITB_Nucleo_configuration.uvprojx");
                if(sysout~=0); errordlg("Error during build,"+newline+"Ask E&P department");end
                sysout=system(app.Path+"UV4.exe"+" -f Keil\MDK-ARM\ITB_Nucleo_configuration.uvprojx");
                if(sysout~=0); errordlg("Error during flash,"+newline+"verify that USB is connected");end
            else
                errordlg("Keil path is wrong,"+newline+"invalid path");
            end
        end

        % Menu selected function: SelezionaSerialeMenu
        function SelezionaSerialeMenuSelected(app, event)
            app.SerialCheck();
        end

        % Button pushed function: StartSendDataButton
        function StartSendDataButtonPushed(app, event)
            if app.InputSent==1
                write(app.SerialPort,app.TxData.ax.Values.Data(app.InputSent),"double");
                write(app.SerialPort,app.TxData.delta.Values.Data(app.InputSent),"double");
                write(app.SerialPort,app.TxData.r.Values.Data(app.InputSent),"double");
                write(app.SerialPort,app.TxData.steering.Values.Data(app.InputSent),"double");
                write(app.SerialPort,app.TxData.vx.Values.Data(app.InputSent),"double");
                write(app.SerialPort,app.TxData.vy.Values.Data(app.InputSent),"double");
                write(app.SerialPort,app.TxData.yrd.Values.Data(app.InputSent),"double");
                app.InputSent=app.InputSent+1;    
            end
            app.RunningLabel.FontColor="Green";
            app.RunningLabel.Text="Running";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImpostazioniMenu = uimenu(app.UIFigure);
            app.ImpostazioniMenu.MenuSelectedFcn = createCallbackFcn(app, @ImpostazioniMenuSelected, true);
            app.ImpostazioniMenu.Text = 'Impostazioni';
            app.KeilPathMenu = uimenu(app.ImpostazioniMenu);
            app.KeilPathMenu.MenuSelectedFcn = createCallbackFcn(app, @KeilPathMenuSelected, true);
            app.KeilPathMenu.Text = 'Keil Path';
            app.AggiornaSoftwareMenu = uimenu(app.ImpostazioniMenu);
            app.AggiornaSoftwareMenu.MenuSelectedFcn = createCallbackFcn(app, @AggiornaSoftwareMenuSelected, true);
            app.AggiornaSoftwareMenu.Text = 'AggiornaSoftware';
            app.SelezionaSerialeMenu = uimenu(app.ImpostazioniMenu);
            app.SelezionaSerialeMenu.MenuSelectedFcn = createCallbackFcn(app, @SelezionaSerialeMenuSelected, true);
            app.SelezionaSerialeMenu.Text = 'Seleziona Seriale';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.StartSendDataButton = uibutton(app.UIFigure, 'push');
            app.StartSendDataButton.ButtonPushedFcn = createCallbackFcn(app, @StartSendDataButtonPushed, true);
            app.StartSendDataButton.Text = 'StartSendData';
            app.RunningLabel = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SerialVisualizator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end